classdef FSCPlotData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        PlotCurrentTempGatherButton  matlab.ui.control.Button
        SaveTempGatherButton         matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        CreateNewButton              matlab.ui.control.Button
        AutomaticUpdateButton        matlab.ui.control.Button
        SetpathtoparentfolderButton  matlab.ui.control.Button
        UpdateButton                 matlab.ui.control.Button
        SrcRecPairtoDisplayPanel     matlab.ui.container.Panel
        RField                       matlab.ui.control.NumericEditField
        SField                       matlab.ui.control.NumericEditField
        ReceiverDropDown             matlab.ui.control.DropDown
        ReceiverDropDownLabel        matlab.ui.control.Label
        SourceDropDown               matlab.ui.control.DropDown
        SourceDropDownLabel          matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        dtAxes                       matlab.ui.control.UIAxes
        centfreqAxes                 matlab.ui.control.UIAxes
        rmsAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run('/Users/tanner/Rice Geophysics Dropbox/Tanner Shadoan/startupTannerMac.m')
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','off')
            set(app.SrcRecPairtoDisplayPanel,'Enable','off')
            set(app.SetpathtoparentfolderButton, 'Enable', 'on')
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','off')
            set(app.SrcRecPairtoDisplayPanel,'Enable','off')
            pause(.1)
            
            % adding the epochs to the previous tempgath stored in the app.
            % Note this addition doesn't do any fancy checking. It checks
            % how many epochs in current tempgahter and adds the latest
            % epochs such that the new tempgather has the number of epochs
            % to the number of cassm sweeps in the data folder. 
            dir = [app.SetpathtoparentfolderButton.UserData '/CASSMdata/'];
            FSCcompletedsi(dir)
            dsitempgath = app.UIFigure.UserData.dsitempgath;
            [dsitempgath,N,L] = addFSCtempgathers(dir,dsitempgath);
            deadtraces = -ones(1,N); deadtraces(1) = 0;
            
            % Data calculation everything is with refernce to the first
            % dataset. 
            srcs = 24;
            recs = 44;
            winsize  = 0.003;
            wintaper = winsize/10;
            load('/Users/tanner/Rice Geophysics Dropbox/Tanner Shadoan/fsbCASSM/TASscripts/FSB_CASSM_RealTime/TravelTimePicksFSC.mat')
            for gath = 1:recs*srcs
                disp(['Now working on ' num2str(gath) '.'])
                pick  = data(gath,1);
                if pick ~= 0
                    %setting dead epochs so to not calculate older epochs again
                    olddeadtraces = dsitempgath.th{gath}(6,1:N);
                    dsitempgath.th{gath}(6,1:N) = -ones(1,N); dsitempgath.th{gath}(6,1) = 0; 
                    
                    %getting the older data so it is not deleted during the
                    %update
                    olddata = dsitempgath.th{gath}(81:84,1:N);
                    
                    
                    t0 = pick + winsize/2;
                    [dsitempgath]                          = dsiCASSMdelaySequenceEstWinPar(dsitempgath,t0,wintaper,winsize,84,dsitempgath.fh{8}/1000,gath,1,1/10000);
                    [dsitempgath.th{gath}(81,:),...
                        dsitempgath.th{gath}(82,:)]        = dsi_rmsAmpWin(dsitempgath,t0,winsize,wintaper,gath);
                    [~,~,~,dsitempgath.th{gath}(83,:),~,~] = dsi_centfreqWin(dsitempgath,t0,winsize,wintaper,gath);
                    dsitempgath.th{gath}(83,:)             = dsitempgath.th{gath}(83,:)/1000;
                    
                    dsitempgath.th{gath}(6,1:N) = olddeadtraces; %setting dead epochs back
                    dsitempgath.th{gath}(81:84,1:N) = olddata;
                end
            end
            
            app.UIFigure.UserData.dsitempgath = dsitempgath;
            
            rmsAxesButtonDown(app,event)
            centfreqAxesButtonDown(app, event)
            dtAxesButtonDown(app, event)
            
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','on')
            set(app.SrcRecPairtoDisplayPanel,'Enable','on')
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','off')
            set(app.SrcRecPairtoDisplayPanel,'Enable','off')
            
            dir         = app.SetpathtoparentfolderButton.UserData;
            savedir     = [dir '/tempgathers/'];
            [file,fpath]= uigetfile(savedir);
            app.UIFigure.UserData = load([fpath file]);
            
            rmsAxesButtonDown(app,event)
            centfreqAxesButtonDown(app, event)
            dtAxesButtonDown(app, event)
            
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','on')
            set(app.SrcRecPairtoDisplayPanel,'Enable','on')
        end

        % Button down function: rmsAxes
        function rmsAxesButtonDown(app, event)
            axInnerPos = app.rmsAxes.InnerPosition;              
            
            S     = str2num(app.SourceDropDown.Value);
            R     = str2num(app.ReceiverDropDown.Value);
            Rmax  = length(app.ReceiverDropDown.Items);
            SRidx = (S-1)*Rmax + R;
            dsitempgath = app.UIFigure.UserData.dsitempgath;
            taxis = dsitaxis(dsitempgath,SRidx);
            data  = dsitempgath.th{SRidx}(81,:);
            plot(app.rmsAxes,taxis,data,'k')
            
            innerPosDiff = axInnerPos - app.rmsAxes.InnerPosition;   
            app.rmsAxes.Position = app.rmsAxes.Position + innerPosDiff;
        end

        % Button down function: centfreqAxes
        function centfreqAxesButtonDown(app, event)
            axInnerPos = app.centfreqAxes.InnerPosition;  
            
            S     = str2num(app.SourceDropDown.Value);
            R     = str2num(app.ReceiverDropDown.Value);
            Rmax  = length(app.ReceiverDropDown.Items);
            SRidx = (S-1)*Rmax + R;
            dsitempgath = app.UIFigure.UserData.dsitempgath;
            taxis = dsitaxis(dsitempgath,SRidx);
            data  = dsitempgath.th{SRidx}(83,:);
            plot(app.centfreqAxes,taxis,data,'k')
            
            innerPosDiff = axInnerPos - app.centfreqAxes.InnerPosition;   
            app.centfreqAxes.Position = app.centfreqAxes.Position + innerPosDiff;
        end

        % Button down function: dtAxes
        function dtAxesButtonDown(app, event)
            axInnerPos = app.dtAxes.InnerPosition;  
            
            S     = str2num(app.SourceDropDown.Value);
            R     = str2num(app.ReceiverDropDown.Value);
            Rmax  = length(app.ReceiverDropDown.Items);
            SRidx = (S-1)*Rmax + R;
            dsitempgath = app.UIFigure.UserData.dsitempgath;
            taxis = dsitaxis(dsitempgath,SRidx);
            data  = dsitempgath.th{SRidx}(84,:);
            plot(app.dtAxes,taxis,data*-1e6,'k')
            
            innerPosDiff = axInnerPos - app.dtAxes.InnerPosition;   
            app.dtAxes.Position = app.dtAxes.Position + innerPosDiff;
        end

        % Value changed function: ReceiverDropDown
        function ReceiverDropDownValueChanged(app, event)
            rmsAxesButtonDown(app,event)
            centfreqAxesButtonDown(app, event)
            dtAxesButtonDown(app, event)
            RFieldValueChanged(app, event)
        end

        % Value changed function: SourceDropDown
        function SourceDropDownValueChanged(app, event)
            rmsAxesButtonDown(app,event)
            centfreqAxesButtonDown(app, event)
            dtAxesButtonDown(app, event)
            SFieldValueChanged(app, event)
        end

        % Button pushed function: CreateNewButton
        function CreateNewButtonPushed(app, event)
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','off')
            set(app.SrcRecPairtoDisplayPanel,'Enable','off')
            
            dir         = app.SetpathtoparentfolderButton.UserData;
            datadir     = uigetdir(dir);
            savedir     = [dir '/tempgathers/'];
            dsitempgath = createInitialTempGather(datadir,savedir);
            app.UIFigure.UserData.dsitempgath = dsitempgath;
            
            rmsAxesButtonDown(app,event)
            centfreqAxesButtonDown(app,event)
            dtAxesButtonDown(app, event)
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','on')
            set(app.SrcRecPairtoDisplayPanel,'Enable','on')
        end

        % Button pushed function: SetpathtoparentfolderButton
        function SetpathtoparentfolderButtonPushed(app, event)
            dir = uigetdir('/Volumes/Tanner_FSC/FSC_CASSM/','Select parent folder containing CASSMdata and tempgathers');
            app.SetpathtoparentfolderButton.UserData = dir;
            
            set(app.SrcRecPairtoDisplayPanel,'Enable','on')
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','on')
        end

        % Button pushed function: AutomaticUpdateButton
        function AutomaticUpdateButtonPushed(app, event)
            disp('Not developed yet')
        end

        % Button pushed function: SaveTempGatherButton
        function SaveTempGatherButtonPushed(app, event)
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','off')
            set(app.SrcRecPairtoDisplayPanel,'Enable','off')
            
            dsitempgath = app.UIFigure.UserData.dsitempgath;
            dir         = app.SetpathtoparentfolderButton.UserData;
            savedir     = [dir '/tempgathers/'];
            [filename, filepath] = uiputfile([savedir '*.mat'], 'Save temp-gather:');
            FileName = fullfile(filepath, filename);
            save(FileName, 'dsitempgath', '-v7.3');
            
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','on')
            set(app.SrcRecPairtoDisplayPanel,'Enable','on')
        end

        % Button pushed function: PlotCurrentTempGatherButton
        function PlotCurrentTempGatherButtonPushed(app, event)
            S     = str2num(app.SourceDropDown.Value);
            R     = str2num(app.ReceiverDropDown.Value);
            Rmax  = length(app.ReceiverDropDown.Items);
            SRidx = (S-1)*Rmax + R;
            dsitempgath = app.UIFigure.UserData.dsitempgath;
            
            figure
                plotgather(dsitempgath,SRidx,1:dsitempgath.fh{1},100)
                title(['S' num2str(S) '-R' num2str(R) ' Pair'])
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {171, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: RField
        function RFieldValueChanged(app, event)
            app.RField.Value = str2num(app.ReceiverDropDown.Value);
        end

        % Value changed function: SField
        function SFieldValueChanged(app, event)
            app.SField.Value = str2num(app.SourceDropDown.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SrcRecPairtoDisplayPanel = uipanel(app.LeftPanel);
            app.SrcRecPairtoDisplayPanel.Title = 'Src - Rec Pair to Display';
            app.SourceDropDownLabel = uilabel(app.SrcRecPairtoDisplayPanel);
            app.SourceDropDownLabel.Text = 'Source #';
            app.SourceDropDown = uidropdown(app.SrcRecPairtoDisplayPanel);
            app.SourceDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.SourceDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceDropDownValueChanged, true);
            app.SourceDropDown.Value = '1';
            app.ReceiverDropDownLabel = uilabel(app.SrcRecPairtoDisplayPanel);
            app.ReceiverDropDownLabel.Text = 'Receiver #';
            app.ReceiverDropDown = uidropdown(app.SrcRecPairtoDisplayPanel);
            app.ReceiverDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44'};
            app.ReceiverDropDown.ValueChangedFcn = createCallbackFcn(app, @ReceiverDropDownValueChanged, true);
            app.ReceiverDropDown.Value = '1';
            app.SField = uieditfield(app.SrcRecPairtoDisplayPanel, 'numeric');
            app.SField.ValueChangedFcn = createCallbackFcn(app, @SFieldValueChanged, true);
            app.SField.Editable = 'off';
            app.SField.Enable = 'off';
            app.SField.Value = 1;
            app.RField = uieditfield(app.SrcRecPairtoDisplayPanel, 'numeric');
            app.RField.ValueChangedFcn = createCallbackFcn(app, @RFieldValueChanged, true);
            app.RField.Editable = 'off';
            app.RField.Enable = 'off';
            app.RField.Value = 1;
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.SetpathtoparentfolderButton = uibutton(app.LeftPanel, 'push');
            app.SetpathtoparentfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetpathtoparentfolderButtonPushed, true);
            app.SetpathtoparentfolderButton.Text = 'Set path to parent folder';
            app.AutomaticUpdateButton = uibutton(app.LeftPanel, 'push');
            app.AutomaticUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticUpdateButtonPushed, true);
            app.AutomaticUpdateButton.Text = 'Automatic Update';
            app.CreateNewButton = uibutton(app.LeftPanel, 'push');
            app.CreateNewButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewButtonPushed, true);
            app.CreateNewButton.Text = 'Create-New';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveTempGatherButton = uibutton(app.LeftPanel, 'push');
            app.SaveTempGatherButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTempGatherButtonPushed, true);
            app.SaveTempGatherButton.Text = 'Save Temp-Gather';
            app.PlotCurrentTempGatherButton = uibutton(app.LeftPanel, 'push');
            app.PlotCurrentTempGatherButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCurrentTempGatherButtonPushed, true);
            app.PlotCurrentTempGatherButton.Text = 'Plot Current Temp-Gather';
            app.RightPanel = uipanel(app.GridLayout);
            app.rmsAxes = uiaxes(app.RightPanel);
            title(app.rmsAxes, 'RMS Amplitude')
            ylabel(app.rmsAxes, 'rms')
            zlabel(app.rmsAxes, 'Z')
            app.rmsAxes.ButtonDownFcn = createCallbackFcn(app, @rmsAxesButtonDown, true);
            app.centfreqAxes = uiaxes(app.RightPanel);
            title(app.centfreqAxes, 'Centroid Frequency')
            ylabel(app.centfreqAxes, 'kHz')
            zlabel(app.centfreqAxes, 'Z')
            app.centfreqAxes.ButtonDownFcn = createCallbackFcn(app, @centfreqAxesButtonDown, true);
            app.dtAxes = uiaxes(app.RightPanel);
            title(app.dtAxes, 'Delay Time')
            xlabel(app.dtAxes, 'Experimental Time')
            ylabel(app.dtAxes, '\mus')
            zlabel(app.dtAxes, 'Z')
            app.dtAxes.ButtonDownFcn = createCallbackFcn(app, @dtAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FSCPlotData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end