classdef FSCPlotDataV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        AutomaticUpdateSwitch        matlab.ui.control.Switch
        AutomaticUpdateSwitchLabel   matlab.ui.control.Label
        ComputeTimelapseImagePanel   matlab.ui.container.Panel
        ComputeandPlotButton         matlab.ui.control.Button
        SelectDataButton             matlab.ui.control.Button
        PlotCurrentTempGatherButton  matlab.ui.control.Button
        SaveTempGatherButton         matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        CreateNewButton              matlab.ui.control.Button
        SetpathtoparentfolderButton  matlab.ui.control.Button
        UpdateButton                 matlab.ui.control.Button
        SrcRecPairtoDisplayPanel     matlab.ui.container.Panel
        RField                       matlab.ui.control.NumericEditField
        SField                       matlab.ui.control.NumericEditField
        ReceiverDropDown             matlab.ui.control.DropDown
        ReceiverDropDownLabel        matlab.ui.control.Label
        SourceDropDown               matlab.ui.control.DropDown
        SourceDropDownLabel          matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        dtAxes                       matlab.ui.control.UIAxes
        centfreqAxes                 matlab.ui.control.UIAxes
        rmsAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run('/Users/tanner/Rice Geophysics Dropbox/Tanner Shadoan/startupTannerMac.m')
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','off')
            set(app.SrcRecPairtoDisplayPanel,'Enable','off')
            set(app.SetpathtoparentfolderButton, 'Enable', 'on')
            set(app.AutomaticUpdateSwitch,'Enable','off')
            set(app.ComputeTimelapseImagePanel,'Enable','off')
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','off')
            set(app.SrcRecPairtoDisplayPanel,'Enable','off')
            set(app.AutomaticUpdateSwitch,'Enable','off')
            set(app.ComputeTimelapseImagePanel,'Enable','off')
            pause(.1)
            
            % adding the epochs to the previous tempgath stored in the app.
            % Note this addition doesn't do any fancy checking. It checks
            % how many epochs in current tempgahter and adds the latest
            % epochs such that the new tempgather has the number of epochs
            % to the number of cassm sweeps in the data folder. 
            DIR= [app.SetpathtoparentfolderButton.UserData '/CASSMdata/'];
            FSCcompletedsi(DIR)
            dsitempgath = app.UIFigure.UserData.dsitempgath;
            [dsitempgath,N,L] = addFSCtempgathers(DIR,dsitempgath);
            deadtraces = -ones(1,N); deadtraces(1) = 0;
            
            % Data calculation everything is with refernce to the first
            % dataset. 
            srcs = 24;
            recs = 44;
            winsize  = 0.003;
            wintaper = winsize/10;
            load('/Users/tanner/Rice Geophysics Dropbox/Tanner Shadoan/fsbCASSM/TASscripts/FSB_CASSM_RealTime/TravelTimePicksFSC.mat');
            for gath = 1:recs*srcs
                disp(['Now working on ' num2str(gath) '.'])
                pick  = data(gath,1);
                if pick ~= 0
                    %setting dead epochs so to not calculate older epochs again
                    olddeadtraces = dsitempgath.th{gath}(6,1:N);
                    dsitempgath.th{gath}(6,1:N) = -ones(1,N); dsitempgath.th{gath}(6,1) = 0; 
                    
                    %getting the older data so it is not deleted during the
                    %update
                    olddata = dsitempgath.th{gath}(81:84,1:N);
                    
                    
                    t0 = pick + winsize/2;
                    [dsitempgath]                          = dsiCASSMdelaySequenceEstWinPar(dsitempgath,t0,wintaper,winsize,84,dsitempgath.fh{8}/1000,gath,1,1/10000);
                    [dsitempgath.th{gath}(81,:),...
                        dsitempgath.th{gath}(82,:)]        = dsi_rmsAmpWin(dsitempgath,t0,winsize,wintaper,gath);
                    [~,~,~,dsitempgath.th{gath}(83,:),~,~] = dsi_centfreqWin(dsitempgath,t0,winsize,wintaper,gath);
                    dsitempgath.th{gath}(83,:)             = dsitempgath.th{gath}(83,:)/1000;
                    
                    dsitempgath.th{gath}(6,1:N) = olddeadtraces; %setting dead epochs back
                    dsitempgath.th{gath}(81:84,1:N) = olddata;
                end
            end
            
            app.UIFigure.UserData.dsitempgath = dsitempgath;
            
            rmsAxesButtonDown(app,event)
            centfreqAxesButtonDown(app, event)
            dtAxesButtonDown(app, event)
            
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','on')
            set(app.SrcRecPairtoDisplayPanel,'Enable','on')
            set(app.AutomaticUpdateSwitch,'Enable','on')
            set(app.ComputeTimelapseImagePanel,'Enable','on')
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','off')
            set(app.SrcRecPairtoDisplayPanel,'Enable','off')
            set(app.AutomaticUpdateSwitch,'Enable','off')
            set(app.ComputeTimelapseImagePanel,'Enable','off')
            
            DIR         = app.SetpathtoparentfolderButton.UserData;
            savedir     = [DIR '/tempgathers/'];
            [file,fpath]= uigetfile(savedir);
            app.UIFigure.UserData = load([fpath file]);
            
            rmsAxesButtonDown(app,event)
            centfreqAxesButtonDown(app, event)
            dtAxesButtonDown(app, event)
            
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','on')
            set(app.SrcRecPairtoDisplayPanel,'Enable','on')
            set(app.AutomaticUpdateSwitch,'Enable','on')
            set(app.ComputeTimelapseImagePanel,'Enable','on')
        end

        % Button down function: rmsAxes
        function rmsAxesButtonDown(app, event)
            axInnerPos = app.rmsAxes.InnerPosition;              
            
            S     = str2num(app.SourceDropDown.Value);
            R     = str2num(app.ReceiverDropDown.Value);
            Rmax  = length(app.ReceiverDropDown.Items);
            SRidx = (S-1)*Rmax + R;
            dsitempgath = app.UIFigure.UserData.dsitempgath;
            taxis = dsitaxis(dsitempgath,SRidx);
            data  = dsitempgath.th{SRidx}(81,:);
            plot(app.rmsAxes,taxis,data,'k')
            
            hold on
            x = datetime('08-May-2023 10:42:00');
            y = get(app.rmsAxes,'YLim');
            line(app.rmsAxes,[x x],y,'Color','b')
            x = datetime('08-May-2023 14:52:00');
            y = get(app.rmsAxes,'YLim');
            line(app.rmsAxes,[x x],y,'Color','b')
            
            x = datetime('09-May-2023 11:59:00');
            y = get(app.rmsAxes,'YLim');
            line(app.rmsAxes,[x x],y,'Color','g')
            x = datetime('09-May-2023 17:17:00');
            y = get(app.rmsAxes,'YLim');
            line(app.rmsAxes,[x x],y,'Color','g')
            hold off
            
            innerPosDiff = axInnerPos - app.rmsAxes.InnerPosition;   
            app.rmsAxes.Position = app.rmsAxes.Position + innerPosDiff;
        end

        % Button down function: centfreqAxes
        function centfreqAxesButtonDown(app, event)
            axInnerPos = app.centfreqAxes.InnerPosition;  
            
            S     = str2num(app.SourceDropDown.Value);
            R     = str2num(app.ReceiverDropDown.Value);
            Rmax  = length(app.ReceiverDropDown.Items);
            SRidx = (S-1)*Rmax + R;
            dsitempgath = app.UIFigure.UserData.dsitempgath;
            taxis = dsitaxis(dsitempgath,SRidx);
            data  = dsitempgath.th{SRidx}(83,:);
            plot(app.centfreqAxes,taxis,data,'k')
            
            hold on
            x = datetime('08-May-2023 10:42:00');
            y = get(app.centfreqAxes,'YLim');
            line(app.centfreqAxes,[x x],y,'Color','b')
            x = datetime('08-May-2023 14:52:00');
            y = get(app.centfreqAxes,'YLim');
            line(app.centfreqAxes,[x x],y,'Color','b')
            
            x = datetime('09-May-2023 11:59:00');
            y = get(app.centfreqAxes,'YLim');
            line(app.centfreqAxes,[x x],y,'Color','g')
            x = datetime('09-May-2023 17:17:00');
            y = get(app.centfreqAxes,'YLim');
            line(app.centfreqAxes,[x x],y,'Color','g')
            hold off
            
            innerPosDiff = axInnerPos - app.centfreqAxes.InnerPosition;   
            app.centfreqAxes.Position = app.centfreqAxes.Position + innerPosDiff;
        end

        % Button down function: dtAxes
        function dtAxesButtonDown(app, event)
            axInnerPos = app.dtAxes.InnerPosition;  
            
            S     = str2num(app.SourceDropDown.Value);
            R     = str2num(app.ReceiverDropDown.Value);
            Rmax  = length(app.ReceiverDropDown.Items);
            SRidx = (S-1)*Rmax + R;
            dsitempgath = app.UIFigure.UserData.dsitempgath;
            taxis = dsitaxis(dsitempgath,SRidx);
            data  = dsitempgath.th{SRidx}(84,:);
            plot(app.dtAxes,taxis,data*-1e6,'k')
            
            hold on
            x = datetime('08-May-2023 10:42:00');
            y = get(app.dtAxes,'YLim');
            line(app.dtAxes,[x x],y,'Color','b')
            x = datetime('08-May-2023 14:52:00');
            y = get(app.dtAxes,'YLim');
            line(app.dtAxes,[x x],y,'Color','b')
            
            x = datetime('09-May-2023 11:59:00');
            y = get(app.dtAxes,'YLim');
            line(app.dtAxes,[x x],y,'Color','g')
            x = datetime('09-May-2023 17:17:00');
            y = get(app.dtAxes,'YLim');
            line(app.dtAxes,[x x],y,'Color','g')
            hold off
         
            innerPosDiff = axInnerPos - app.dtAxes.InnerPosition;   
            app.dtAxes.Position = app.dtAxes.Position + innerPosDiff;
        end

        % Value changed function: ReceiverDropDown
        function ReceiverDropDownValueChanged(app, event)
            rmsAxesButtonDown(app,event)
            centfreqAxesButtonDown(app, event)
            dtAxesButtonDown(app, event)
            RFieldValueChanged(app, event)
        end

        % Value changed function: SourceDropDown
        function SourceDropDownValueChanged(app, event)
            rmsAxesButtonDown(app,event)
            centfreqAxesButtonDown(app, event)
            dtAxesButtonDown(app, event)
            SFieldValueChanged(app, event)
        end

        % Button pushed function: CreateNewButton
        function CreateNewButtonPushed(app, event)
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','off')
            set(app.SrcRecPairtoDisplayPanel,'Enable','off')
            set(app.AutomaticUpdateSwitch,'Enable','off')
            set(app.ComputeTimelapseImagePanel,'Enable','off')
            
            DIR         = app.SetpathtoparentfolderButton.UserData;
            datadir     = uigetdir(DIR);
            savedir     = [DIR '/tempgathers/'];
            dsitempgath = createInitialTempGather(datadir,savedir);
            app.UIFigure.UserData.dsitempgath = dsitempgath;
            
            rmsAxesButtonDown(app,event)
            centfreqAxesButtonDown(app,event)
            dtAxesButtonDown(app, event)
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','on')
            set(app.SrcRecPairtoDisplayPanel,'Enable','on')
            set(app.AutomaticUpdateSwitch,'Enable','on')
            set(app.ComputeTimelapseImagePanel,'Enable','on')
        end

        % Button pushed function: SetpathtoparentfolderButton
        function SetpathtoparentfolderButtonPushed(app, event)
            DIR = uigetdir('/Volumes/Tanner_FSC/FSC_CASSM/','Select parent folder containing CASSMdata and tempgathers');
            app.SetpathtoparentfolderButton.UserData = DIR;
            
            set(app.SrcRecPairtoDisplayPanel,'Enable','on')
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','on')
            set(app.AutomaticUpdateSwitch,'Enable','on')
            set(app.ComputeTimelapseImagePanel,'Enable','on')
        end

        % Value changed function: AutomaticUpdateSwitch
        function AutomaticUpdateButtonPushed(app, event)
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','off')
            set(app.ComputeTimelapseImagePanel,'Enable','off')
            rawdatapath     = [app.SetpathtoparentfolderButton.UserData '/FSB_CASSM_Geodes_2023'];
            workingdatapath = [app.SetpathtoparentfolderButton.UserData '/CASSMdata'];
            while strcmp(app.AutomaticUpdateSwitch.Value,'On')
                disp('Looking for new files...')
                newfolderlist = newfoldercheck(rawdatapath,workingdatapath);

                Lnew = length(newfolderlist);
                
                if Lnew > 0
                    for i = 1:Lnew
                        disp(['checking new folder ' newfolderlist{i} ' (' num2str(i) '/' num2str(Lnew) '):'])
                        folderpath = [rawdatapath '/' newfolderlist{i}];
                        foldersize = 0;
                        while foldersize ~= 34
                            disp('    waiting for folder download completely...')
                            files = dir([folderpath '/*.dat']);
                            foldersize  = round(sum([files(1:end).bytes])/1e6);
                            pause(10)
                        end
                        movefile(folderpath,workingdatapath);
                        disp('...now moving folder to working directory')
                        disp(' ')
                    end
                    % adding the epochs to the previous tempgath stored in the app.
                    % Note this addition doesn't do any fancy checking. It checks
                    % how many epochs in current tempgahter and adds the latest
                    % epochs such that the new tempgather has the number of epochs
                    % to the number of cassm sweeps in the data folder. 
                    DIR = [app.SetpathtoparentfolderButton.UserData '/CASSMdata/'];
                    FSCcompletedsi(DIR)
                    dsitempgath = app.UIFigure.UserData.dsitempgath;
                    [dsitempgath,N,L] = addFSCtempgathers(DIR,dsitempgath);
                    deadtraces = -ones(1,N); deadtraces(1) = 0;
                    
                    % Data calculation everything is with refernce to the first
                    % dataset. 
                    srcs = 24;
                    recs = 44;
                    winsize  = 0.003;
                    wintaper = winsize/10;
                    load('/Users/tanner/Rice Geophysics Dropbox/Tanner Shadoan/fsbCASSM/TASscripts/FSB_CASSM_RealTime/TravelTimePicksFSC.mat');
                    for gath = 1:recs*srcs
                        disp(['Now working on ' num2str(gath) '.'])
                        pick  = data(gath,1);
                        if pick ~= 0
                            %setting dead epochs so to not calculate older epochs again
                            olddeadtraces = dsitempgath.th{gath}(6,1:N);
                            dsitempgath.th{gath}(6,1:N) = -ones(1,N); dsitempgath.th{gath}(6,1) = 0; 
                            
                            %getting the older data so it is not deleted during the
                            %update
                            olddata = dsitempgath.th{gath}(81:84,1:N);
                            
                            
                            t0 = pick + winsize/2;
                            [dsitempgath]                          = dsiCASSMdelaySequenceEstWinPar(dsitempgath,t0,wintaper,winsize,84,dsitempgath.fh{8}/1000,gath,1,1/10000);
                            [dsitempgath.th{gath}(81,:),...
                                dsitempgath.th{gath}(82,:)]        = dsi_rmsAmpWin(dsitempgath,t0,winsize,wintaper,gath);
                            [~,~,~,dsitempgath.th{gath}(83,:),~,~] = dsi_centfreqWin(dsitempgath,t0,winsize,wintaper,gath);
                            dsitempgath.th{gath}(83,:)             = dsitempgath.th{gath}(83,:)/1000;
                            
                            dsitempgath.th{gath}(6,1:N) = olddeadtraces; %setting dead epochs back
                            dsitempgath.th{gath}(81:84,1:N) = olddata;
                        end
                    end
                    
                    app.UIFigure.UserData.dsitempgath = dsitempgath;
                    
                    rmsAxesButtonDown(app,event)
                    centfreqAxesButtonDown(app, event)
                    dtAxesButtonDown(app, event)
                end
                pause(300)
            end
            
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','on')
            set(app.ComputeTimelapseImagePanel,'Enable','on')

        end

        % Button pushed function: SaveTempGatherButton
        function SaveTempGatherButtonPushed(app, event)
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','off')
            set(app.SrcRecPairtoDisplayPanel,'Enable','off')
            set(app.AutomaticUpdateSwitch,'Enable','off')
            set(app.ComputeTimelapseImagePanel,'Enable','off')
            
            dsitempgath = app.UIFigure.UserData.dsitempgath;
            DIR         = app.SetpathtoparentfolderButton.UserData;
            savedir     = [DIR '/tempgathers/'];
            [filename, filepath] = uiputfile([savedir '*.mat'], 'Save temp-gather:');
            FileName = fullfile(filepath, filename);
            save(FileName, 'dsitempgath', '-v7.3');
            
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','on')
            set(app.SrcRecPairtoDisplayPanel,'Enable','on')
            set(app.AutomaticUpdateSwitch,'Enable','on')
            set(app.ComputeTimelapseImagePanel,'Enable','on')
        end

        % Button pushed function: PlotCurrentTempGatherButton
        function PlotCurrentTempGatherButtonPushed(app, event)
            S     = str2num(app.SourceDropDown.Value);
            R     = str2num(app.ReceiverDropDown.Value);
            Rmax  = length(app.ReceiverDropDown.Items);
            SRidx = (S-1)*Rmax + R;
            dsitempgath = app.UIFigure.UserData.dsitempgath;
            
            figure(10)
                plotgather(dsitempgath,SRidx,1:dsitempgath.fh{1},100)
                title(['S' num2str(S) '-R' num2str(R) ' Pair'])
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {171, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: RField
        function RFieldValueChanged(app, event)
            app.RField.Value = str2num(app.ReceiverDropDown.Value);
        end

        % Value changed function: SField
        function SFieldValueChanged(app, event)
            app.SField.Value = str2num(app.SourceDropDown.Value);
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            delete(findall(app.UIFigure,'Type','hggroup'));
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','off')
            set(app.SrcRecPairtoDisplayPanel,'Enable','off')
            set(app.AutomaticUpdateSwitch,'Enable','off')
            set(app.ComputeTimelapseImagePanel,'Enable','off')
            
            
            dc = datacursormode(app.UIFigure);
            dc.Enable = 'on';
            point = [];
            while isempty(point)
                point = getCursorInfo(dc);
                pause(0.1)
            end
            dc.Enable = 'off';
            app.SelectDataButton.UserData = point;
            
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','on')
            set(app.SrcRecPairtoDisplayPanel,'Enable','on')
            set(app.AutomaticUpdateSwitch,'Enable','on')
            set(app.ComputeTimelapseImagePanel,'Enable','on')
        end

        % Button pushed function: ComputeandPlotButton
        function ComputeandPlotButtonPushed(app, event)
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','off')
            set(app.SrcRecPairtoDisplayPanel,'Enable','off')
            set(app.AutomaticUpdateSwitch,'Enable','off')
            set(app.ComputeTimelapseImagePanel,'Enable','off')
            pause(0.1)
            
            point = app.SelectDataButton.UserData.DataIndex;
            dsitempgath = app.UIFigure.UserData.dsitempgath;
            
            
            epoch_date = dsitempgath.th{1056}(65:70,point);
            datestring = [num2str(epoch_date(1),'%04.f') num2str(epoch_date(2),'%02.f') num2str(epoch_date(3),'%02.f') num2str(epoch_date(4),'%02.f') num2str(epoch_date(5),'%02.f') num2str(epoch_date(6),'%02.f')];
            delete(findall(app.UIFigure,'Type','hggroup'));
            
            
            load('/Users/tanner/Rice Geophysics Dropbox/Tanner Shadoan/fsbCASSM/TASscripts/FSB_CASSM_RealTime/avgdtbackground.mat')
            for i = 1:1056
                try
                data(i) = dsitempgath.th{i}(84,point)-avgdtbackground(i);
                catch
                end
            end
            badSources            = [((18-1)*44+1):18*44 ((23-1)*44+1):23*44];
            data(badSources) = [];
            outstructTL = instruct_timelapseFSC(data);   
   
            rotateboreholes; close all;
            [I1, check1] = plane_line_intersect([1 0 0],[0.5 0 0],Borehole_B1(1,:),Borehole_B1(2,:));
            [I2, check2] = plane_line_intersect([1 0 0],[0.5 0 0],Borehole_B2(1,:),Borehole_B2(2,:));
            [I3, check3] = plane_line_intersect([1 0 0],[0.5 0 0],BCS_D7(1,:),BCS_D7(2,:));
            [I4, check4] = plane_line_intersect([1 0 0],[0.5 0 0],BFS1(1,:),BFS1(2,:));
            [I5, check5] = plane_line_intersect([1 0 0],[0.5 0 0],BFS2(1,:),BFS2(2,:));
            [I6, check6] = plane_line_intersect([1 0 0],[0.5 0 0],Borehole_B11(1,:),Borehole_B11(2,:));
            [I7, check7] = plane_line_intersect([1 0 0],[0.5 0 0],Borehole_B12(1,:),Borehole_B12(2,:));
            
            par = outstructTL.instruct.par;
            par.ymin = 0;
            par.zmin = 0;
            f = figure(100);
                hold on
                Vp = outstructTL.deltavel;
                [M,X,Y] = plotslice3D(Vp,par,'Z-Y',0.5,1,'V_P (m/s)',[]);
                scatter(X(2) - (I1(2)+19),I1(3)+36,250,'k.','LineWidth',3)
                scatter(X(2) - (I2(2)+19),I2(3)+36,250,'m.','LineWidth',3)
                scatter(X(2) - (I3(2)+19),I3(3)+36,250,'k.','LineWidth',3)
                scatter(X(2) - (I4(2)+19),I4(3)+36,250,'k.','LineWidth',3)
                scatter(X(2) - (I5(2)+19),I5(3)+36,250,'k.','LineWidth',3)
                scatter(X(2) - (I6(2)+19),I6(3)+36,250,'k.','LineWidth',3)
                scatter(X(2) - (I7(2)+19),I7(3)+36,250,'k.','LineWidth',3)
                scatter(X(2) - (I2(2)+19)+12,I2(3)+36,'mp','MarkerFaceColor','w','MarkerEdgeColor','k','LineWidth',0.5)
                ylabel('Distance along dip direction (m)')
                xlabel('Distance along strike (m)')
                hold off
                set(gca,'FontName','Helvetica')
                set(gca,'FontSize',10)
                colorbar
                map = BWR2(256);
                colormap(map)
                patch([0.5 28 33.5 33 20.5 12],[14.5 12 40 56 54.5 42.5],'k','FaceColor','none','EdgeColor','k')
                set(gca,'Layer','top')
                caxis([-25 25])
                box on
                title(datestring)
                
            DIR = app.SetpathtoparentfolderButton.UserData;
            exportgraphics(f,[DIR '/images/' datestring '.jpg'],'Resolution',400)
            h = findobj(app.UIFigure,'Type','uibutton');
            set(h,'Enable','on')
            set(app.SrcRecPairtoDisplayPanel,'Enable','on')
            set(app.AutomaticUpdateSwitch,'Enable','on')
            set(app.ComputeTimelapseImagePanel,'Enable','on')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SrcRecPairtoDisplayPanel = uipanel(app.LeftPanel);
            app.SrcRecPairtoDisplayPanel.Title = 'Src - Rec Pair to Display';
            app.SourceDropDownLabel = uilabel(app.SrcRecPairtoDisplayPanel);
            app.SourceDropDownLabel.Text = 'Source #';
            app.SourceDropDown = uidropdown(app.SrcRecPairtoDisplayPanel);
            app.SourceDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.SourceDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceDropDownValueChanged, true);
            app.SourceDropDown.Value = '1';
            app.ReceiverDropDownLabel = uilabel(app.SrcRecPairtoDisplayPanel);
            app.ReceiverDropDownLabel.Text = 'Receiver #';
            app.ReceiverDropDown = uidropdown(app.SrcRecPairtoDisplayPanel);
            app.ReceiverDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44'};
            app.ReceiverDropDown.ValueChangedFcn = createCallbackFcn(app, @ReceiverDropDownValueChanged, true);
            app.ReceiverDropDown.Value = '1';
            app.SField = uieditfield(app.SrcRecPairtoDisplayPanel, 'numeric');
            app.SField.ValueChangedFcn = createCallbackFcn(app, @SFieldValueChanged, true);
            app.SField.Editable = 'off';
            app.SField.Enable = 'off';
            app.SField.Value = 1;
            app.RField = uieditfield(app.SrcRecPairtoDisplayPanel, 'numeric');
            app.RField.ValueChangedFcn = createCallbackFcn(app, @RFieldValueChanged, true);
            app.RField.Editable = 'off';
            app.RField.Enable = 'off';
            app.RField.Value = 1;
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.SetpathtoparentfolderButton = uibutton(app.LeftPanel, 'push');
            app.SetpathtoparentfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetpathtoparentfolderButtonPushed, true);
            app.SetpathtoparentfolderButton.Text = 'Set path to parent folder';
            app.CreateNewButton = uibutton(app.LeftPanel, 'push');
            app.CreateNewButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewButtonPushed, true);
            app.CreateNewButton.Text = 'Create-New';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveTempGatherButton = uibutton(app.LeftPanel, 'push');
            app.SaveTempGatherButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTempGatherButtonPushed, true);
            app.SaveTempGatherButton.Text = 'Save Temp-Gather';
            app.PlotCurrentTempGatherButton = uibutton(app.LeftPanel, 'push');
            app.PlotCurrentTempGatherButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCurrentTempGatherButtonPushed, true);
            app.PlotCurrentTempGatherButton.Text = 'Plot Current Temp-Gather';
            app.ComputeTimelapseImagePanel = uipanel(app.LeftPanel);
            app.ComputeTimelapseImagePanel.Title = 'Compute Timelapse Image';
            app.SelectDataButton = uibutton(app.ComputeTimelapseImagePanel, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            app.ComputeandPlotButton = uibutton(app.ComputeTimelapseImagePanel, 'push');
            app.ComputeandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeandPlotButtonPushed, true);
            app.ComputeandPlotButton.Text = 'Compute and Plot';
            app.AutomaticUpdateSwitchLabel = uilabel(app.LeftPanel);
            app.AutomaticUpdateSwitchLabel.Text = 'Automatic Update';
            app.AutomaticUpdateSwitch = uiswitch(app.LeftPanel, 'slider');
            app.AutomaticUpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @AutomaticUpdateButtonPushed, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.rmsAxes = uiaxes(app.RightPanel);
            title(app.rmsAxes, 'RMS Amplitude')
            ylabel(app.rmsAxes, 'rms')
            zlabel(app.rmsAxes, 'Z')
            app.rmsAxes.ButtonDownFcn = createCallbackFcn(app, @rmsAxesButtonDown, true);
            app.centfreqAxes = uiaxes(app.RightPanel);
            title(app.centfreqAxes, 'Centroid Frequency')
            ylabel(app.centfreqAxes, 'kHz')
            zlabel(app.centfreqAxes, 'Z')
            app.centfreqAxes.ButtonDownFcn = createCallbackFcn(app, @centfreqAxesButtonDown, true);
            app.dtAxes = uiaxes(app.RightPanel);
            title(app.dtAxes, 'Delay Time')
            xlabel(app.dtAxes, 'Experimental Time')
            ylabel(app.dtAxes, '\mus')
            zlabel(app.dtAxes, 'Z')
            app.dtAxes.ButtonDownFcn = createCallbackFcn(app, @dtAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FSCPlotDataV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end